classdef ImageFeaturesAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CataloguePanel                matlab.ui.container.Panel
        ExtensionDropDownLabel        matlab.ui.control.Label
        ExtensionDropDown             matlab.ui.control.DropDown
        ChoosepathButton              matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        stimageEditFieldLabel         matlab.ui.control.Label
        stimageEditField              matlab.ui.control.NumericEditField
        ndimageEditFieldLabel         matlab.ui.control.Label
        ndimageEditField              matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        DescriptorsButtonGroup        matlab.ui.container.ButtonGroup
        SURFButton                    matlab.ui.control.ToggleButton
        BRISKButton                   matlab.ui.control.ToggleButton
        HOGButton                     matlab.ui.control.ToggleButton
        FASTButton                    matlab.ui.control.ToggleButton
        ORBButton                     matlab.ui.control.ToggleButton
        MSERButton                    matlab.ui.control.ToggleButton
        HARRISButton                  matlab.ui.control.ToggleButton
        ColormodelSwitchLabel         matlab.ui.control.Label
        ColormodelSwitch              matlab.ui.control.Switch
        SimilaritythresholdEditFieldLabel  matlab.ui.control.Label
        SimilaritythresholdEditField  matlab.ui.control.NumericEditField
        CompareButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        numberOfFiles;  %number of all files
        I;              %first image
        I2;             %second image
        currentImage1Number =1;
        currentImage2Number =2;
        myFolder;
        theFiles;
        
        selectedDescriptor = 'SURF';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosepathButton
        function ChoosepathButtonPushed(app, event)
            % Specify the folder where the files live. 
app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory')                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.jpg'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
app.theFiles = dir(filePattern);
for k = 1: length(app.theFiles)
  baseFileName = app.theFiles(k).name;
  fullFileName = fullfile(app.myFolder, baseFileName);
end
  file1 = fullfile(app.myFolder, app.theFiles(1).name);
  file2 = fullfile(app.myFolder, app.theFiles(2).name);
    app.I = imread(file1);
    imshow(app.I,'Parent', app.UIAxes);
    app.I2 = imread(file2);
    imshow(app.I2, 'Parent', app.UIAxes_2 );
  
  drawnow; % Force display to update immediately.
  app.numberOfFiles = length(app.theFiles);
  formatSpec = '/%d';
    app.Label.Text = sprintf(formatSpec,app.numberOfFiles);
    app.Label_2.Text = sprintf(formatSpec,app.numberOfFiles);
        end

        % Value changed function: stimageEditField
        function stimageEditFieldValueChanged(app, event)
            value = app.stimageEditField.Value;
            if value > app.currentImage2Number
                warndlg('1st image must be befor 2nd image')
            else
            file1 = fullfile(app.myFolder, app.theFiles(value).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;
            app.currentImage1Number = value;
            end
        end

        % Value changed function: ndimageEditField
        function ndimageEditFieldValueChanged(app, event)
            value = app.ndimageEditField.Value;
            if value < app.currentImage1Number
                warndlg('2nd image must be after 1st image')
            else
            file2 = fullfile(app.myFolder, app.theFiles(value).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = value;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if(app.currentImage2Number == app.numberOfFiles)
            file1 = fullfile(app.myFolder, app.theFiles(1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number = 1; 
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(2).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = 2; 
            app.ndimageEditField.Value = app.currentImage2Number;
            else 
                
            file1 = fullfile(app.myFolder, app.theFiles(app.currentImage1Number+1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number=app.currentImage1Number+1;
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.currentImage2Number+1).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.currentImage2Number + 1; 
            app.ndimageEditField.Value = app.currentImage2Number;
            end

                
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(app.currentImage1Number == 1)
            file1 = fullfile(app.myFolder, app.theFiles(app.numberOfFiles-1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number = app.numberOfFiles-1; 
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.numberOfFiles).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.numberOfFiles; 
            app.ndimageEditField.Value = app.currentImage2Number;
            else 
                
            file1 = fullfile(app.myFolder, app.theFiles(app.currentImage1Number-1).name);
            app.I = imread(file1);
            imshow(app.I,'Parent', app.UIAxes);
            drawnow;           
            app.currentImage1Number=app.currentImage1Number-1;
            app.stimageEditField.Value = app.currentImage1Number;
            
            file2 = fullfile(app.myFolder, app.theFiles(app.currentImage2Number-1).name);
            app.I2 = imread(file2);
            imshow(app.I2,'Parent', app.UIAxes_2);
            drawnow;
            app.currentImage2Number = app.currentImage2Number-1; 
            app.ndimageEditField.Value = app.currentImage2Number;
            end
        end

        % Selection changed function: DescriptorsButtonGroup
        function DescriptorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DescriptorsButtonGroup.SelectedObject.Text;
            
            app.selectedDescriptor = selectedButton;                      
            
            Im1gray=rgb2gray(app.I);
            Im2gray = rgb2gray(app.I2);
            
switch app.selectedDescriptor
    case 'SURF'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectSURFFeatures(Im1gray);
        points2 = detectSURFFeatures(Im2gray);

        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        plot(strongest1, app.UIAxes, 'showOrientation',true);
        plot(strongest2, app.UIAxes_2, 'showOrientation',true);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'HOG'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        [hog1,visualization1] = extractHOGFeatures(Im1gray,'CellSize',[60 60]);
        [hog2,visualization2] = extractHOGFeatures(Im2gray,'CellSize',[60 60]);
        
        plot(visualization1, app.UIAxes);
        plot(visualization2, app.UIAxes_2);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'BRISK'      
        points1 = detectBRISKFeatures(Im1gray);
        points2 = detectBRISKFeatures(Im2gray);
        
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        plot(strongest1, app.UIAxes, 'showOrientation',true);
        plot(strongest2, app.UIAxes_2, 'showOrientation',true);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'FAST'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectFASTFeatures(Im1gray);
        points2 = detectFASTFeatures(Im2gray);
        
        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        plot(strongest1, app.UIAxes);
        plot(strongest2, app.UIAxes_2);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'ORB'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectORBFeatures(Im1gray);
        points2 = detectORBFeatures(Im2gray);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        plot(points1, app.UIAxes, 'showScale',false);
        plot(points2, app.UIAxes_2, 'showScale',false);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'MSER'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        regions1 = detectMSERFeatures(Im1gray);
        regions2 = detectMSERFeatures(Im2gray);
        
        [features1, valid_points1] = extractFeatures(Im1gray, regions1,'Upright',true);
        [features2, valid_points2] = extractFeatures(Im2gray, regions2, "Upright",true);
        
        plot(valid_points1, app.UIAxes,'showOrientation',true);
        plot(valid_points2,app.UIAxes_2,'showOrientation',true);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'HARRIS'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        corners1 = detectHarrisFeatures(Im1gray);
        corners2 = detectHarrisFeatures(Im2gray);
        
        [features1, valid_corners1] = extractFeatures(Im1gray, corners1);
        [features2, valid_corners2] = extractFeatures(Im2gray, corners2);

        plot(valid_corners1, app.UIAxes);
        plot(valid_corners2, app.UIAxes_2);
        
       
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    otherwise
        disp('other value')
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CataloguePanel = uipanel(app.UIFigure);
            app.CataloguePanel.Title = 'Catalogue';
            app.ExtensionDropDownLabel = uilabel(app.CataloguePanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.CataloguePanel);
            app.ExtensionDropDown.Items = {'.tif', '.dcm', '.jpg', '.png'};
            app.ExtensionDropDown.Value = '.tif';
            app.ChoosepathButton = uibutton(app.CataloguePanel, 'push');
            app.ChoosepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosepathButtonPushed, true);
            app.ChoosepathButton.Text = 'Choose path';
            app.ImagesPanel = uipanel(app.UIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes_2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.stimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.stimageEditFieldLabel.Text = '1st image';
            app.stimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.stimageEditField.ValueChangedFcn = createCallbackFcn(app, @stimageEditFieldValueChanged, true);
            app.stimageEditField.Value = 1;
            app.ndimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.ndimageEditFieldLabel.Text = '2nd image';
            app.ndimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.ndimageEditField.ValueChangedFcn = createCallbackFcn(app, @ndimageEditFieldValueChanged, true);
            app.ndimageEditField.Value = 2;
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ImagesPanel);
            app.Label_2.Text = '';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DescriptorsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DescriptorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DescriptorsButtonGroupSelectionChanged, true);
            app.DescriptorsButtonGroup.Title = 'Descriptors';
            app.SURFButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.SURFButton.Value = true;
            app.BRISKButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.BRISKButton.Text = 'BRISK';
            app.HOGButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.FASTButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.FASTButton.Text = 'FAST';
            app.ORBButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.MSERButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.MSERButton.Text = 'MSER';
            app.HARRISButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HARRISButton.Text = 'HARRIS';
            app.ColormodelSwitchLabel = uilabel(app.OptionsPanel);
            app.ColormodelSwitchLabel.Text = 'Color model';
            app.ColormodelSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ColormodelSwitch.Items = {'RGB', 'G+B'};
            app.ColormodelSwitch.Value = 'RGB';
            app.SimilaritythresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.SimilaritythresholdEditFieldLabel.Text = 'Similarity threshold ';
            app.SimilaritythresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CompareButton = uibutton(app.OptionsPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFeaturesAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end